classdef SAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAMAutomaticSegmentationToolUIFigure  matlab.ui.Figure
        SAMSegmentationToolButton       matlab.ui.control.Button
        OutputFolderEditField           matlab.ui.control.EditField
        InputFolderEditField            matlab.ui.control.EditField
        SegmentationInfoPanel           matlab.ui.container.Panel
        InfoTextArea                    matlab.ui.control.TextArea
        InputParametersPanel            matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        AreaScalingFactorEditField      matlab.ui.control.NumericEditField
        AreaScalingFactorLabel          matlab.ui.control.Label
        MinimumAreaObjectSizeEditField  matlab.ui.control.NumericEditField
        MinimumAreaObjectSizeEditFieldLabel  matlab.ui.control.Label
        DiskRadiusEditField             matlab.ui.control.NumericEditField
        DiskRadiusEditFieldLabel        matlab.ui.control.Label
        ScaleFactorEditField            matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel       matlab.ui.control.Label
        LoadModelButton                 matlab.ui.control.Button
        OutputFolderButton              matlab.ui.control.Button
        InputFolderButton               matlab.ui.control.Button
        SegmentationButton              matlab.ui.control.Button
    end


    properties (Access = private)
        inputFolder = '';       % Cartella di input
        outputFolder = '';      % Cartella di output
        samObj = [];            % Modello SAM
        imageFiles = [];        % Lista file immagine
        currentFrame = 1;       % Frame corrente
        BW = [];                % Maschera binaria
        minObjectSize = 100;    % Dimensione minima oggetto
        correctionStartFrame = 1; % Frame iniziale per correzione
        correctionEndFrame = 1;  % Frame finale per correzione
        manualMask = [];        % Maschera manuale creata dall'utente % Description
        modelLoaded = false; % Flag per indicare se il modello è caricato
        modelLoadingDialog % Dialog per il caricamento del modello

    end

    methods (Access = private)


        function updateSegmentationInfo(app, meanArea, minObjectSize)
            infoText = {
                sprintf('Mean Segmented Area: %.2f px', meanArea),
                sprintf('Minimum Object Size: %d px', minObjectSize),

                };
            app.InfoTextArea.Value = infoText;
        end

        function startupFcn(app)
            % Imposta valori di default
            app.ScaleFactorEditField.Value = 0.3;
            app.DiskRadiusEditField.Value = 2;
            app.MinimumAreaObjectSizeEditField.Value = 100;
            app.AreaScalingFactorEditField.Value = 0.005;
            % Disabilita il pulsante di segmentazione all'avvio
            app.SegmentationButton.Enable = 'off';
            % Inizializza le proprietà aggiunte
            app.modelLoadingDialog = [];
            app.modelLoaded = false;
            app.InfoTextArea.Value = {'Waiting for first segmentation...'};

        end

        % Funzione helper per ordinamento naturale
        function [sortedNames, index] = sort_nat(~, names)
            % Natural order sort of cell array of strings
            if isempty(names)
                sortedNames = {};
                index = [];
                return;
            end

            % Extract numbers
            num = regexp(names, '(\d+)', 'tokens');
            num = cellfun(@(x) str2double(x{1}), num, 'UniformOutput', false);
            num(cellfun(@isempty, num)) = {Inf};
            num = cell2mat(num);

            % Sort
            [~, index] = sort(num);
            sortedNames = names(index);
        end

        % Funzione per la segmentazione iniziale manuale
        function runInitialSegmentation(app, scaleFactor, diskRadius, minAreaThreshold, areaThresholdFactor)
            % Mostra barra di caricamento durante l'inizializzazione
            loadingDialog = uiprogressdlg(app.SAMAutomaticSegmentationToolUIFigure,...
                'Title','Preparation in progress',...
                'Message','Preparing the interface for manual segmentation...',...
                'Indeterminate','on');

            try
                % Carica il primo frame
                firstImagePath = fullfile(app.imageFiles(1).folder, app.imageFiles(1).name);
                I = imread(firstImagePath);

                % Aggiorna messaggio barra di caricamento
                loadingDialog.Message = 'Loading interface for segmentation...';
                drawnow; % Forza l'aggiornamento della UI

                % Estrai embeddings (usa GPU se disponibile)
                if canUseGPU
                    I_gpu = gpuArray(I);
                else
                    I_gpu = I;
                end

                embeddings = extractEmbeddings(app.samObj, I_gpu);

                % Chiude la barra di caricamento
                if isvalid(loadingDialog)
                    close(loadingDialog);
                end

                % Crea figura separata per la selezione ROI
                f = figure('Name', 'Selection ROI - Frame 1', 'NumberTitle', 'off',...
                    'WindowStyle', 'modal'); % Modal per evitare interazioni con l'app principale
                hIm = imshow(I);
                app.BW = false(size(I,1), size(I,2));

                % Loop di selezione ROI
                selectionComplete = false;
                while ~selectionComplete
                    choice = questdlg('Choose type of selection:', ...
                        'ROI Mode', ...
                        'Rectangle', 'Manual contour', 'End', 'Rectangle');

                    switch choice
                        case 'Rectangle'
                            roi = drawrectangle(gca, 'Color', 'r');
                            box = roi.Position;
                            currMask = segmentObjectsFromEmbeddings(app.samObj, embeddings, size(I), 'BoundingBox', box);
                            app.BW = app.BW | gather(currMask);
                            delete(roi);

                        case 'Manual contour'
                            roi = drawfreehand(gca, 'Color', 'b');
                            maskManual = createMask(roi);
                            if ~any(maskManual(:))
                                warning('Empty or invalid contour');
                                continue;
                            end
                            app.BW = app.BW | maskManual;
                            delete(roi);

                        case 'End'
                            selectionComplete = true;

                        otherwise
                            close(f);
                            return;
                    end

                    % Mostra anteprima con overlay colorato
                    hIm.CData = labeloverlay(I, app.BW,...
                        'Colormap', [1 0 0; 0 1 0],...
                        'Transparency', 0.7);

                    % Se l'utente ha scelto "Termina", chiedi conferma
                    if selectionComplete
                        confirm = questdlg('Are you satisfied with the selection made?', ...
                            'Confirm selection', ...
                            'Yes, proceed', 'No, remake', 'Delete', 'Yes, proceed');

                        switch confirm
                            case 'Yes, proceed'
                                selectionComplete = true;
                            case 'No, remake'
                                app.BW = false(size(I,1), size(I,2));
                                hIm.CData = I;
                                selectionComplete = false;
                            case 'Delete'
                                close(f);
                                return;
                        end
                    end
                end

                close(f);

                % Salva la maschera iniziale
                [~, baseName, ~] = fileparts(app.imageFiles(1).name);
                maskPath = fullfile(app.outputFolder, [baseName '_mask.png']);
                imwrite(app.BW, maskPath);

                % Calcola minObjectSize automaticamente
                stats = regionprops(app.BW, 'Area');
                if isempty(stats)
                    app.minObjectSize = 100;
                    warning('No object found in the initial mask');
                    app.InfoTextArea.Value = {
                        'No objects found in initial mask',
                        'Using default minObjectSize: 100 pixels'
                        };
                else
                    meanArea = mean([stats.Area]);
                    app.minObjectSize = round(meanArea * scaleFactor);
                    fprintf('Automatic minimum object size: %d px\n', app.minObjectSize);
                    % Aggiorna il TextArea
                    app.updateSegmentationInfo(meanArea, app.minObjectSize);

                    fprintf('Mean segmented area = %.2f px. Auto minObjectSize threshold set to %d px.\n',...
                        meanArea, app.minObjectSize);
                end

                % Estrai bounding boxes iniziali
                stats = regionprops(app.BW, 'BoundingBox');
                if isempty(stats)
                    uialert(app.SAMAutomaticSegmentationToolUIFigure, 'No object found to extract bounding boxes', 'Error');
                    return;
                end
                bboxes = vertcat(stats.BoundingBox);

                % Esegui segmentazione automatica sui frame successivi
                app.runAutomaticSegmentation(embeddings, bboxes, diskRadius, minAreaThreshold, areaThresholdFactor, true);

                % Chiedi feedback all'utente con dialog più informativo
                userSatisfied = questdlg({...
                    'Is the automatic segmentation satisfying?',...
                    '',...
                    'Choose "No, correct" to manually edit some frames',...
                    'Choose "Cancel" to stop the process'}, ...
                    'Segmentation Feedback', ...
                    'Yes', 'No, correct', 'Cancel', 'Yes');

                switch userSatisfied
                    case 'Yes'
                        msgbox('Segmentation completed successfully!', 'Completed', 'help');
                    case 'No, correct'
                        app.runCorrectionMode(scaleFactor, diskRadius, minAreaThreshold, areaThresholdFactor);
                    case 'Cancel'
                        return;
                end

            catch ME
                % Gestione errori con cleanup
                if isvalid(loadingDialog)
                    close(loadingDialog);
                end
                errMsg = sprintf('Error initializing segmentation:\n%s', ME.message);
                uialert(app.SAMAutomaticSegmentationToolUIFigure, errMsg, 'Error', 'Icon', 'error');

                % Log dell'errore completo per debug
                fprintf(2, 'Error in runInitialSegmentation:\n');
                fprintf(2, '%s\n', getReport(ME, 'extended', 'hyperlinks', 'off'));
                return;
            end
        end

        % Segmentazione automatica sui frame successivi
        function runAutomaticSegmentation(app, initialEmbeddings, initialBBoxes, diskRadius, minAreaThreshold, areaThresholdFactor, isInitialRun)
            currentEmbeddings = initialEmbeddings;
            currentBBoxes = initialBBoxes;

            % Determina il range di frame da elaborare
            if isInitialRun
                startFrame = 2; % Per la prima esecuzione, parte dal frame 2
                endFrame = app.correctionEndFrame;
            else
                startFrame = app.correctionStartFrame + 1; % Parte dal frame successivo a quello selezionato
                endFrame = app.correctionEndFrame;
            end

            % Verifica che ci siano frame da elaborare
            if startFrame > endFrame
                return;
            end

            totalFrames = endFrame - startFrame + 1;
            currentFrame = 0;

            hWaitbar = waitbar(0, sprintf('Processing frame %d/%d', startFrame, endFrame), ...
                'Name', 'Automatic Segmentation', ...
                'CreateCancelBtn', 'setappdata(gcbf,''canceling'',1)');

            setappdata(hWaitbar, 'canceling', 0);

            for k = startFrame:endFrame
                % Check for Cancel button press
                if getappdata(hWaitbar, 'canceling')
                    break;
                end

                currentFrame = currentFrame + 1;

                try
                    % Aggiorna la barra di progresso
                    progress = currentFrame / totalFrames;
                    waitbar(progress, hWaitbar, ...
                        sprintf('Processing frame %d/%d', k, endFrame));

                    % Carica il frame corrente
                    framePath = fullfile(app.imageFiles(k).folder, app.imageFiles(k).name);
                    currImg = imread(framePath);
                    imgSize = size(currImg);

                    % Estrai embeddings
                    if canUseGPU
                        currImgGPU = gpuArray(currImg);
                    else
                        currImgGPU = currImg;
                    end
                    embeddings = extractEmbeddings(app.samObj, currImgGPU);

                    % Inizializza maschera
                    totalMask = false(imgSize(1), imgSize(2));

                    % Segmenta usando i bounding box correnti
                    for i = 1:size(currentBBoxes,1)
                        currMask = segmentObjectsFromEmbeddings(app.samObj, embeddings, imgSize, 'BoundingBox', currentBBoxes(i,:));
                        totalMask = totalMask | gather(currMask);
                    end

                    % Pulisci la maschera
                    [L, num] = bwlabel(totalMask);
                    stats = regionprops(L, 'Area');
                    cleanMask = false(size(totalMask));

                    for i = 1:num
                        if stats(i).Area >= app.minObjectSize
                            cleanMask = cleanMask | (L == i);
                        end
                    end

                    % Operazioni morfologiche se necessarie
                    [imageHeight, imageWidth, ~] = size(currImg);
                    dynamicAreaThreshold = areaThresholdFactor * imageHeight * imageWidth;

                    if mean([stats.Area]) > dynamicAreaThreshold
                        totalMask = imclose(cleanMask, strel('disk', diskRadius));
                        totalMask = imfill(totalMask, 'holes');
                    else
                        totalMask = cleanMask;
                    end

                    % Salva la maschera
                    [~, name, ~] = fileparts(app.imageFiles(k).name);
                    imwrite(totalMask, fullfile(app.outputFolder, [name '_mask.png']));

                    % Aggiorna embeddings e bounding boxes
                    maskedImage = currImg;
                    if size(maskedImage,3) == 3
                        maskedImage(repmat(~totalMask, [1 1 3])) = 0;
                    else
                        maskedImage(~totalMask) = 0;
                    end

                    if canUseGPU
                        maskedImageGPU = gpuArray(maskedImage);
                    else
                        maskedImageGPU = maskedImage;
                    end

                    currentEmbeddings = extractEmbeddings(app.samObj, maskedImageGPU);

                    stats = regionprops(totalMask, 'BoundingBox');
                    if ~isempty(stats)
                        newBBoxes = vertcat(stats.BoundingBox);

                        % Filtra bounding boxes troppo piccoli
                        validBoxes = [];
                        for j = 1:size(newBBoxes,1)
                            if newBBoxes(j,3) * newBBoxes(j,4) > minAreaThreshold
                                validBoxes = [validBoxes; newBBoxes(j,:)];
                            end
                        end

                        if ~isempty(validBoxes)
                            currentBBoxes = validBoxes;
                        end
                    end

                catch ME
                    warning('Error in frame %d: %s', k, ME.message);
                end
            end

            delete(hWaitbar); % Chiudi la barra di progresso
        end

        % Modalità correzione
        function runCorrectionMode(app, scaleFactor, diskRadius, minAreaThreshold, areaThresholdFactor)
            answer = inputdlg({'Initial frame to correct:', 'Final frame to correct:'}, ...
                'Segmentation Correction', [1 50], ...
                {num2str(min(2, length(app.imageFiles))), num2str(length(app.imageFiles))});

            if isempty(answer)
                return;
            end

            app.correctionStartFrame = round(str2double(answer{1}));
            app.correctionEndFrame = round(str2double(answer{2}));

            if isnan(app.correctionStartFrame) || isnan(app.correctionEndFrame) || ...
                    app.correctionStartFrame < 1 || app.correctionEndFrame > length(app.imageFiles) || app.correctionStartFrame > app.correctionEndFrame
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'Invalid frame range', 'Error');
                return;
            end

            % Scelta modalità correzione
            modeChoice = questdlg('Select the type of correction:', ...
                'Correction Mode', ...
                'Overwrite all', ...
                'Add to existing masks', ...
                'Overwrite all');

            % Carica il frame di partenza per la correzione
            framePath = fullfile(app.imageFiles(app.correctionStartFrame).folder, app.imageFiles(app.correctionStartFrame).name);
            I = imread(framePath);

            % Estrai embeddings
            if canUseGPU
                I_gpu = gpuArray(I);
            else
                I_gpu = I;
            end
            embeddings = extractEmbeddings(app.samObj, I_gpu);

            % Crea figura separata per la correzione
            f = figure('Name', sprintf('Correction - Frame %d', app.correctionStartFrame), 'NumberTitle', 'off');
            hIm = imshow(I);
            app.manualMask = false(size(I,1), size(I,2));

            % Se in modalità "aggiungi", carica la maschera esistente
            if strcmp(modeChoice, 'Add to existing masks')
                [~, name, ~] = fileparts(app.imageFiles(app.correctionStartFrame).name);
                maskPath = fullfile(app.outputFolder, [name '_mask.png']);
                if isfile(maskPath)
                    existingMask = imread(maskPath);
                    if ~islogical(existingMask)
                        existingMask = existingMask > 0;
                    end
                    app.manualMask = existingMask;
                    hIm.CData = labeloverlay(I, app.manualMask, 'Colormap', 'autumn');
                end
            end

            % Loop di selezione ROI per la correzione
            selectionComplete = false;
            while ~selectionComplete
                choice = questdlg('Choose the type of selection:', ...
                    'ROI Mode', ...
                    'Rectangle', 'Manual contour', 'End', 'Rectangle');

                switch choice
                    case 'Rectangle'
                        roi = drawrectangle(gca);
                        box = roi.Position;
                        currMask = segmentObjectsFromEmbeddings(app.samObj, embeddings, size(I), 'BoundingBox', box);
                        app.manualMask = app.manualMask | gather(currMask);
                        delete(roi);

                    case 'Manual contour'
                        roi = drawfreehand(gca);
                        maskManual = createMask(roi);
                        if ~any(maskManual(:))
                            warning('Empty or invalid contour');
                            continue;
                        end
                        app.manualMask = app.manualMask | maskManual;
                        delete(roi);

                    case 'End'
                        selectionComplete = true;

                    otherwise
                        close(f);
                        return;
                end

                % Mostra anteprima
                hIm.CData = labeloverlay(I, app.manualMask, 'Colormap', 'autumn');

                % Se l'utente ha scelto "Termina", chiedi conferma
                if selectionComplete
                    confirm = questdlg('Are you satisfied with the selection made?', ...
                        'Confirm selection', ...
                        'Yes, proceed', 'No, remake', 'Cancel', 'Yes, proceed');

                    switch confirm
                        case 'Yes, proceed'
                            % Procedi con la segmentazione
                            selectionComplete = true;
                        case 'No, remake'
                            % Resetta e continua
                            app.manualMask = false(size(I,1), size(I,2));
                            hIm.CData = I;
                            selectionComplete = false;
                        case 'Cancel'
                            close(f);
                            return;
                    end
                end
            end

            close(f);

            % Salva la maschera manuale (NON sovrascritta dalla segmentazione automatica)
            [~, name, ~] = fileparts(app.imageFiles(app.correctionStartFrame).name);
            imwrite(app.manualMask, fullfile(app.outputFolder, [name '_mask.png']));

            % Calcola minObjectSize sulla maschera manuale
            stats = regionprops(app.manualMask, 'Area');
            if isempty(stats)
                app.minObjectSize = 100;
                app.InfoTextArea.Value = {
                    'No objects found after correction',
                    'Using default minObjectSize: 100 pixels'
                    };
            else
                meanArea = mean([stats.Area]);
                app.minObjectSize = round(meanArea * scaleFactor);
                app.updateSegmentationInfo(meanArea, app.minObjectSize);
            end

            % Estrai bounding boxes dalla maschera manuale
            stats = regionprops(app.manualMask, 'BoundingBox');
            if isempty(stats)
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'No object found after the correction', 'Error');
                return;
            end
            bboxes = vertcat(stats.BoundingBox);

            % Esegui segmentazione automatica SOLO sui frame successivi
            app.runAutomaticSegmentation(embeddings, bboxes, diskRadius, minAreaThreshold, areaThresholdFactor, false);

            % Chiedi nuovamente feedback all'utente
            userSatisfied = questdlg('Is the correction satysfing?', ...
                'Correction feedback', 'Yes', 'No, correct again', 'Cancel', 'Yes');

            switch userSatisfied
                case 'Yes'
                    msgbox('Correction completed succesfully!', 'Completed');
                case 'No, correct again'
                    app.runCorrectionMode(scaleFactor, diskRadius, minAreaThreshold, areaThresholdFactor);
                case 'Cancel'
                    return;
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputFolderButton
        function InputFolderButtonPushed(app, event)
            app.inputFolder = uigetdir(pwd, 'Select frame folder');
            if app.inputFolder == 0
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'No folder selected', 'Warning');
                return;
            end

            app.InputFolderEditField.Value = app.inputFolder;

            % Carica la lista delle immagini
            app.imageFiles = dir(fullfile(app.inputFolder, '*.png'));
            if isempty(app.imageFiles)
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'No PNG files found in the folder', 'Error');
                return;
            end

            % Ordina i file numericamente
            [~, idx] = app.sort_nat({app.imageFiles.name});
            app.imageFiles = app.imageFiles(idx);

            uialert(app.SAMAutomaticSegmentationToolUIFigure, sprintf('Loaded %d frame', length(app.imageFiles)), 'Success');
        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
            app.outputFolder = uigetdir(pwd, 'Select the folder to save the masks');
            if app.outputFolder == 0
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'No folder selected', 'Warning');
            else
                app.OutputFolderEditField.Value = app.outputFolder;
                uialert(app.SAMAutomaticSegmentationToolUIFigure, sprintf('Output folder set: %s', app.outputFolder), 'Success');
            end
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            % Crea e mostra la dialog box di caricamento
            app.modelLoadingDialog = uiprogressdlg(app.SAMAutomaticSegmentationToolUIFigure,...
                'Title','Processing in progress',...
                'Message','Loading SAM Model...',...
                'Indeterminate','on');

            try
                % Disabilita il pulsante durante il caricamento
                app.LoadModelButton.Enable = 'off';
                drawnow; % Forza l'aggiornamento dell'interfaccia

                % Carica il modello (operazione che potrebbe richiedere tempo)
                app.samObj = segmentAnythingModel;
                app.modelLoaded = true;

                % Chiude la dialog box
                if isvalid(app.modelLoadingDialog)
                    close(app.modelLoadingDialog);
                end

                % Abilita il pulsante di segmentazione
                app.SegmentationButton.Enable = 'on';

                % Mostra messaggio di successo
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'SAM model loaded successfully', 'Success');

            catch ME
                % In caso di errore, chiude la dialog e mostra messaggio
                if isvalid(app.modelLoadingDialog)
                    close(app.modelLoadingDialog);
                end

                % Riabilita il pulsante
                app.LoadModelButton.Enable = 'on';
                app.modelLoaded = false;

                uialert(app.SAMAutomaticSegmentationToolUIFigure, sprintf('Error loading the model: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            % Verifica prerequisiti
            if isempty(app.samObj)
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'First, load SAM model', 'Error');
                return;
            end

            if isempty(app.inputFolder) || isempty(app.outputFolder)
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'Select both input and output folders', 'Error');
                return;
            end

            % Ottieni parametri dall'interfaccia
            scaleFactor = app.ScaleFactorEditField.Value;
            diskRadius = app.DiskRadiusEditField.Value;
            minAreaThreshold = app.MinimumAreaObjectSizeEditField.Value;
            areaThresholdFactor = app.AreaScalingFactorEditField.Value;

            % Imposta i frame per la correzione (tutti inizialmente)
            app.correctionStartFrame = 1;
            app.correctionEndFrame = length(app.imageFiles);

            % Inizia la segmentazione
            app.runInitialSegmentation(scaleFactor, diskRadius, minAreaThreshold, areaThresholdFactor);
        end

        % Callback function
        function SAMSegmentationToolButtonPushed(app, event)
            infoText = {
                ' AUTOMATIC SEGMENTATION USING THE SEGMENT ANYTHING MODEL';
                '';
                ' ==============================================================================';
                ' AUTHOR: Sarneri Michele';
                '';
                ' DATE  : [06/06/2025]';
                '';
                ' FUNCTION: AutomaticSegmentation';
                '';
                ' PURPOSE:';
                '';
                ' This MATLAB function performs automatic segmentation on a sequence of video';
                ' frames or images, using image processing techniques to identify and isolate';
                ' regions of interest with just an inital manual intervention. The process is designed';
                ' for applications in medical , industrial, or scientific domains where extracting';
                ' relevant regions from raw visual data is essential. It can also be used in the field ';
                ' of video surveillance to identify subjects of interest taken as a reference.';
                '';
                ' FUNCTIONAL OVERVIEW:';
                '';
                ' 1. Accepts an array or cell array of frames/images as input.';
                ' 2. Applies a series of preprocessing steps (grayscale conversion, filtering,';
                '    normalization).';
                ' 3. Uses segmentation algorithms (e.g., adaptive thresholding, edge detection,';
                '    clustering, or morphological operations) to isolate target regions.';
                ' 4. Post-processes the segmented output to enhance accuracy by removing noise';
                '    and eliminating irrelevant small regions.';
                ' 5. Returns the segmented masks and/or overlays the results on the original frames.';
                '';
                ' INPUT PARAMETERS:';
                '';
                ' - inputFolder with frameData: Array or cell array containing the video frames/images to be segmented.';
                ' - parameters: Optional structure specifying segmentation settings (e.g., thresholds, filters, minimum region size, etc.).';
                ' - outputFolder: (Optional) Path to save the segmented images.';
                '';
                ' OUTPUT:';
                ' - Binary masks or segmented images, returned as arrays or saved to disk.';
                ' - (Optional) Interactive display of the segmentation results.';
                '';
                ' FEATURES:';
                '';
                ' - Fully automatic segmentation, with a small group of inputs by the user.';
                ' - Configurable for different domains through customizable parameters.';
                ' - Supports batch processing of long image or video sequences.';
                ' - Modular codebase, easy to extend with additional segmentation techniques.';
                '';
                ' REQUIREMENTS:';
                '';
                ' - MATLAB with the Image Processing Toolbox.';
                ' - MATLAB with the Deep Learning Toolbox.';
                ' - MATLAB with the Mapping Toolbox.';
                ' - MATLAB with the Medical Imaging Toolbox.';
                ' - MATLAB with the Computer Vision Toolbox.';
                ' - MATLAB with the Image Processing Toolbox Model for Segment Anything Model';
                ' - Images must be preloaded or acquired from file/video.';
                '';
                ' USAGE EXAMPLE:';
                '   AutomaticSegmentation(frameData, struct(''threshold'', 0.3, ''minArea'', 50), ''C:\segmented\'');';
                '';
                ' ==============================================================================';
                };

            % Crea una finestra di dialogo con il testo
            fig = uifigure('Name', 'SAM Segmentation Tool Info', 'Position', [100 100 800 600]);
            txt = uitextarea(fig, 'Value', infoText, 'Position', [20 20 760 560], ...
                'FontName', 'FixedWidth', 'Editable', 'off');
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Testo delle spiegazioni
            helpText = {
                '1. Scale Factor:';
                '';
                'Defines the radius of the structuring element used for morphological operations on the binary image. A higher scale factor results in a larger disk-shaped structuring element, which has a stronger effect on smoothing the image, removing small objects, or merging nearby regions.';
                '';
                'Higher value: more aggressive filtering, may removes small or fine details;'
                '';
                'Lower value: preserves details, less aggressive morphological effect.';
                '';
                '2. Disk Radius:';
                '';
                'Specifies the radius (in pixels) of the circular structuring element used in morphological operations (e.g., noise removal or gap closing).';
                '';
                '3. Min Area Object Size:';
                '';
                'Defines the minimum area (in pixels) that an object must have to be considered valid. Smaller objects are ignored as noise or artifacts.';
                '';
                '4. Area Scaling Factor:';
                '';
                'This is a multiplier applied to the average (or median) area of detected objects in the image. It is used to dynamically set a threshold for filtering out objects that are significantly larger or smaller than typical ones.';
                '';
                'example: If the average object area is 100 px² and the factor is 1.5, the threshold becomes 150 px² (Objects outside this range can be excluded to improve accuracy).'
                '';
                'Higher values: stricter filtering, allowing only objects close to average size.';
                '';
                'Lower values: more tolerant to variations in object size';
                };

            % Crea il pop-up con uialert (integrato con App Designer)
            uialert(app.SAMAutomaticSegmentationToolUIFigure, helpText, 'Parameters Help', ...
                'Icon', 'info', ...
                'Interpreter', 'none');
        end

        % Button pushed function: SAMSegmentationToolButton
        function SAMSegmentationToolButtonPushed2(app, event)
            infoText = {
                ' AUTOMATIC SEGMENTATION USING THE SEGMENT ANYTHING MODEL';
                '';
                ' ==============================================================================';
                ' AUTHOR: Sarneri Michele';
                '';
                ' DATE  : [06/06/2025]';
                '';
                ' FUNCTION: AutomaticSegmentation';
                '';
                ' PURPOSE:';
                '';
                ' This MATLAB function performs automatic segmentation on a sequence of video';
                ' frames or images, using image processing techniques to identify and isolate';
                ' regions of interest with just an inital manual intervention. The process is designed';
                ' for applications in medical , industrial, or scientific domains where extracting';
                ' relevant regions from raw visual data is essential. It can also be used in the field ';
                ' of video surveillance to identify subjects of interest taken as a reference.';
                '';
                ' FUNCTIONAL OVERVIEW:';
                '';
                ' 1. Accepts an array or cell array of frames/images as input.';
                ' 2. Applies a series of preprocessing steps (grayscale conversion, filtering,';
                '    normalization).';
                ' 3. Uses segmentation algorithms (e.g., adaptive thresholding, edge detection,';
                '    clustering, or morphological operations) to isolate target regions.';
                ' 4. Post-processes the segmented output to enhance accuracy by removing noise';
                '    and eliminating irrelevant small regions.';
                ' 5. Returns the segmented masks and/or overlays the results on the original frames.';
                '';
                ' INPUT PARAMETERS:';
                '';
                ' - inputFolder with frameData: Array or cell array containing the video frames/images to be segmented.';
                ' - parameters: Optional structure specifying segmentation settings (e.g., thresholds, filters, minimum region size, etc.).';
                ' - outputFolder: (Optional) Path to save the segmented images.';
                '';
                ' OUTPUT:';
                ' - Binary masks or segmented images, returned as arrays or saved to disk.';
                ' - (Optional) Interactive display of the segmentation results.';
                '';
                ' FEATURES:';
                '';
                ' - Fully automatic segmentation, with a small group of inputs by the user.';
                ' - Configurable for different domains through customizable parameters.';
                ' - Supports batch processing of long image or video sequences.';
                ' - Modular codebase, easy to extend with additional segmentation techniques.';
                '';
                ' REQUIREMENTS:';
                '';
                ' - MATLAB with the Image Processing Toolbox.';
                ' - MATLAB with the Deep Learning Toolbox.';
                ' - MATLAB with the Mapping Toolbox.';
                ' - MATLAB with the Medical Imaging Toolbox.';
                ' - MATLAB with the Computer Vision Toolbox.';
                ' - MATLAB with the Image Processing Toolbox Model for Segment Anything Model';
                ' - Images must be preloaded or acquired from file/video.';
                '';
                ' USAGE EXAMPLE:';
                '   AutomaticSegmentation(frameData, struct(''threshold'', 0.3, ''minArea'', 50), ''C:\segmented\'');';
                '';
                ' ==============================================================================';
                };

            % Crea una finestra di dialogo con il testo
            fig = uifigure('Name', 'SAM Segmentation Tool Info', 'Position', [100 100 800 600]);
            txt = uitextarea(fig, 'Value', infoText, 'Position', [20 20 760 560], ...
                'FontName', 'FixedWidth', 'Editable', 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SAMAutomaticSegmentationToolUIFigure = uifigure('Visible', 'off');
            app.SAMAutomaticSegmentationToolUIFigure.Name = '(SAM) Automatic Segmentation Tool';
            app.SegmentationButton = uibutton(app.SAMAutomaticSegmentationToolUIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Tag = 'SegmentationButton';
            app.SegmentationButton.Enable = 'off';
            app.SegmentationButton.Text = 'Segmentation';
            app.InputFolderButton = uibutton(app.SAMAutomaticSegmentationToolUIFigure, 'push');
            app.InputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @InputFolderButtonPushed, true);
            app.InputFolderButton.Tag = 'BrowseInputButton';
            app.InputFolderButton.Text = 'Input Folder';
            app.OutputFolderButton = uibutton(app.SAMAutomaticSegmentationToolUIFigure, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Tag = 'BrowseOutputButton';
            app.OutputFolderButton.Text = 'Output Folder';
            app.LoadModelButton = uibutton(app.SAMAutomaticSegmentationToolUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.InputParametersPanel = uipanel(app.SAMAutomaticSegmentationToolUIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.ScaleFactorEditFieldLabel = uilabel(app.InputParametersPanel);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.ScaleFactorEditField.Value = 0.3;
            app.DiskRadiusEditFieldLabel = uilabel(app.InputParametersPanel);
            app.DiskRadiusEditFieldLabel.Text = 'Disk Radius';
            app.DiskRadiusEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.DiskRadiusEditField.Value = 2;
            app.MinimumAreaObjectSizeEditFieldLabel = uilabel(app.InputParametersPanel);
            app.MinimumAreaObjectSizeEditFieldLabel.Text = 'Minimum Area Object Size';
            app.MinimumAreaObjectSizeEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.MinimumAreaObjectSizeEditField.Value = 100;
            app.AreaScalingFactorLabel = uilabel(app.InputParametersPanel);
            app.AreaScalingFactorLabel.Text = 'Area Scaling Factor';
            app.AreaScalingFactorEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.AreaScalingFactorEditField.Value = 0.005;
            app.Image = uiimage(app.InputParametersPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons8-Windows-8-Files-Add-List.512.png');
            app.SegmentationInfoPanel = uipanel(app.SAMAutomaticSegmentationToolUIFigure);
            app.SegmentationInfoPanel.Title = 'Segmentation Info Panel        ';
            app.InfoTextArea = uitextarea(app.SegmentationInfoPanel);
            app.InfoTextArea.Tag = 'InfoText Area';
            app.InputFolderEditField = uieditfield(app.SAMAutomaticSegmentationToolUIFigure, 'text');
            app.OutputFolderEditField = uieditfield(app.SAMAutomaticSegmentationToolUIFigure, 'text');
            app.SAMSegmentationToolButton = uibutton(app.SAMAutomaticSegmentationToolUIFigure, 'push');
            app.SAMSegmentationToolButton.ButtonPushedFcn = createCallbackFcn(app, @SAMSegmentationToolButtonPushed2, true);
            app.SAMSegmentationToolButton.Text = 'SAM Segmentation Tool';
            % Show the figure after all components are created
            app.SAMAutomaticSegmentationToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAMAutomaticSegmentationToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAMAutomaticSegmentationToolUIFigure)
        end
    end
end