classdef STABA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AutomaticAlignmentButton   matlab.ui.control.Button
        SelectinputfolderButton    matlab.ui.control.Button
        EditField_4                matlab.ui.control.EditField
        FromEditField_4            matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.EditField
        FromEditField_3            matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.EditField
        FromEditField_2            matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.EditField
        FromEditField              matlab.ui.control.NumericEditField
        SetoutputfpsButton         matlab.ui.control.Button
        SetreferenceframeButton    matlab.ui.control.Button
        SelectoutputfolderButton   matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        EditField2                 matlab.ui.control.EditField
        ButtonGroup                matlab.ui.container.ButtonGroup
        SURFButton                 matlab.ui.control.RadioButton
        GradientCorrelationButton  matlab.ui.control.RadioButton
    end

    properties (Access = private)
        FrameData
        VideoFrameRate
        SelectedAlgorithm = 'Gradient Correlation'; % Default Value
        OutputFolder
        inputFolder
        imageFiles
        ProgressBar                % Progress bar dialog

    end

    methods (Access = private)

        function [sorted, index] = sort_nat(~, cellArray)
            % Natural order sort for strings with numbers (e.g., img1, img2, img10)

            expr = '\d+';
            numPart = regexp(cellArray, expr, 'match');

            numVal = cellfun(@(x) str2double(x{1}), numPart);
            [~, index] = sort(numVal);
            sorted = cellArray(index);
        end

        function updateProgressBar(app, message, value)
            % Update progress bar with message and value
            if isvalid(app.ProgressBar)
                app.ProgressBar.Message = message;
                app.ProgressBar.Value = value;
                drawnow;
            end
        end

        function closeProgressBar(app)
            % Close progress bar if it exists
            if ~isempty(app.ProgressBar) && isvalid(app.ProgressBar)
                close(app.ProgressBar);
                app.ProgressBar = [];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FromEditField_2.Value = 0; % Default value for output FPS
            app.FromEditField_3.Value = 1; % Default reference frame
            app.FromEditField_4.Value = 0; % Initially 0 frames
        end

        % Button pushed function: SetoutputfpsButton
        function SetoutputfpsButtonPushed(app, event)
            prompt = {'Enter the desired FPS:'}; % question
            dlgtitle = 'Set Output FPS'; % dialog title
            dims = [1 35];
            definput = {num2str(app.FromEditField.Value)}; % Default value
            response = inputdlg(prompt, dlgtitle, dims, definput);

            if ~isempty(response)
                fps_output = str2double(response{1});

                % Check if the input is a valid number
                if isnan(fps_output)
                    uialert(app.UIFigure, 'Please enter a valid number.', 'Error');
                    return;
                end

                % Check if the value is negative or zero
                if fps_output <= 0
                    uialert(app.UIFigure, 'FPS must be greater than zero.', 'Error');
                    return;
                end

                % If everything is valid, update the field
                app.FromEditField_2.Value = fps_output;
            end
        end

        % Button pushed function: SetreferenceframeButton
        function SetreferenceframeButtonPushed(app, event)
            prompt = {'Enter the reference frame number:'}; % question
            dlgtitle = 'Set Reference Frame'; % dialog title
            dims = [1 35];
            definput = {num2str(app.FromEditField_3.Value)}; % Default value
            response = inputdlg(prompt, dlgtitle, dims, definput);

            if ~isempty(response)
                frame_ref = str2double(response{1});

                % Check if the input is a valid number
                if isnan(frame_ref)
                    uialert(app.UIFigure, 'Please enter a valid number.', 'Error');
                    return;
                end

                % Check if it is negative or zero
                if frame_ref <= 0
                    uialert(app.UIFigure, 'The reference frame must be greater than zero.', 'Error');
                    return;
                end

                % Check if it exceeds the total number of frames
                total_frames = app.FromEditField_4.Value;
                if frame_ref > total_frames
                    uialert(app.UIFigure, sprintf('The reference frame number (%g) cannot be greater than the total number of frames (%g).', ...
                        frame_ref, total_frames), 'Error');
                    return;
                end

                % If everything is valid, update the field
                app.FromEditField_3.Value = frame_ref;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.SelectedAlgorithm = selectedButton.Text;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Check if input folder has been selected
            if isempty(app.inputFolder) || isempty(app.FrameData)
                uialert(app.UIFigure, 'Please select an input folder first.', 'Error');
                return;
            end

            % Retrieve the parameters saved in the app
            Frame_Data = app.FrameData;
            frameRate = app.FromEditField_2.Value;
            referenceFrame = app.FromEditField_3.Value;
            outputFolder = app.OutputFolder;
            if isempty(outputFolder)
                uialert(app.UIFigure, 'Please select an output folder first.', 'Error');
                return;
            end

            % Check which option is selected in the ButtonGroup
            selectedMethod = app.ButtonGroup.SelectedObject.Text;

            % Disable Start button during processing
            app.StartButton.Enable = 'off';

            % Create progress bar
            app.ProgressBar = uiprogressdlg(app.UIFigure, 'Title', 'Processing', ...
                'Message', 'Starting stabilization...', 'Indeterminate', 'on');

            try
                % Execute the selected stabilizer
                switch selectedMethod
                    case 'SURF'
                        app.updateProgressBar('Starting SURF stabilization...', 0.1);
                        % Call the SURF stabilization function
                        app_stabilizer_SURF2_par(Frame_Data, outputFolder, frameRate, referenceFrame, app.inputFolder);
                        app.updateProgressBar('SURF stabilization completed!', 1.0);
                        uialert(app.UIFigure, 'Stabilization with SURF completed!', 'Success');

                    case 'Gradient Correlation'
                        app.updateProgressBar('Starting Gradient Correlation stabilization...', 0.1);
                        % Call the Gradient Correlation stabilization function
                        app_stabilizer_gradientCorrelation_par(Frame_Data, outputFolder, frameRate, referenceFrame, app.inputFolder);
                        app.updateProgressBar('Gradient Correlation stabilization completed!', 1.0);
                        uialert(app.UIFigure, 'Stabilization with Gradient Correlation completed!', 'Success');

                    otherwise
                        % If no method is selected
                        uialert(app.UIFigure, 'Please select a stabilization method.', 'Error');
                end

            catch ME
                % Close progress bar in case of error
                app.closeProgressBar();
                % Re-enable Start button
                app.StartButton.Enable = 'on';
                % Show error message
                uialert(app.UIFigure, sprintf('An error occurred: %s', ME.message), 'Error');
                return;
            end

            % Close progress bar
            app.closeProgressBar();
            % Re-enable Start button
            app.StartButton.Enable = 'on';
        end

        % Button pushed function: SelectoutputfolderButton
        function SelectoutputfolderButtonPushed(app, event)
            folderPath = uigetdir('', 'Select the output folder');
            if folderPath ~= 0
                app.OutputFolder = folderPath;
                uialert(app.UIFigure, ['Selected folder: ' folderPath], 'Output Folder');
            else
                uialert(app.UIFigure, 'No folder selected.', 'Warning');
            end
        end

        % Button pushed function: SelectinputfolderButton
        function SelectinputfolderButtonPushed(app, event)
            app.inputFolder = uigetdir(pwd, 'Select frame folder');

            if app.inputFolder == 0
                uialert(app.UIFigure, 'No folder selected', 'Warning');
                return;
            end

            % Load the list of images
            app.imageFiles = dir(fullfile(app.inputFolder, '*.png'));
            if isempty(app.imageFiles)
                uialert(app.UIFigure, 'No PNG files found in the folder', 'Error');
                return;
            end

            % Sort files numerically
            [~, idx] = app.sort_nat({app.imageFiles.name});
            app.imageFiles = app.imageFiles(idx);

            uialert(app.UIFigure, sprintf('Loaded %d frames', length(app.imageFiles)), 'Success');

            % Try to recover the FPS from VideoStructure if Staba was launched by ViFoSe.
            fps = 30;  % Default value

            try
                % If VideoStructure exists in the workspace (passed from ViFoSe)
                if evalin('base', 'exist(''VideoStructure'', ''var'')')
                    VideoStructure = evalin('base', 'VideoStructure');
                    if isfield(VideoStructure, 'frameRate')
                        fps = VideoStructure.frameRate;
                        uialert(app.UIFigure, ...
                            sprintf('FPS loaded from ViFoSe: %.2f', fps), ...
                            'FPS Detected');
                    else
                        error('VideoStructure exists but frameRate field not found');
                    end
                else
                    % If STABA is launched standalone, use default FPS.
                    uialert(app.UIFigure, ...
                        'Using default FPS = 30. For original FPS, launch from ViFoSe.', ...
                        'Info', 'Icon', 'info');
                end
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Could not read video FPS. Using default FPS = 30.\nError: %s', ME.message), ...
                    'Warning', 'Icon', 'warning');
            end

            % Load each image into Frame_Data
            Frame_Data = cell(1, length(app.imageFiles));
            for i = 1:length(app.imageFiles)
                Frame_Data{i} = imread(fullfile(app.inputFolder, app.imageFiles(i).name));
            end

            % Save the extracted data in the app's properties for later use
            app.FrameData = Frame_Data;

            % Also save the frame rate in a separate property
            app.VideoFrameRate = fps;

            % Update input fields with the video information
            app.FromEditField.Value = app.VideoFrameRate; % Original video FPS
            app.FromEditField_2.Value = app.VideoFrameRate; % Default FPS for the stabilized video (same as original)
            app.FromEditField_4.Value = numel(Frame_Data); % Total number of frames
        end

        % Button pushed function: AutomaticAlignmentButton
        function AutomaticAlignmentButtonPushed(app, event)
            infoText = {
                ' STABA - STABilization Automatic Alignment';
                '';
                ' ==============================================================================';
                ' AUTHOR: [Abdelrahman Abdelaziz Mohamed]';
                '';
                ' DATE  : [11/11/2025]';
                '';
                ' FUNCTION: STABA - Video Stabilization Tool';
                '';
                ' PURPOSE:';
                '';
                ' This MATLAB application performs automatic video stabilization on sequences of';
                ' image frames using advanced image processing and computer vision techniques.';
                ' The tool is designed to reduce unwanted camera motion and jitter in video footage,';
                ' improving visual quality and stability for applications in scientific analysis,';
                ' surveillance, cinematography, and multimedia production.';
                '';
                ' FUNCTIONAL OVERVIEW:';
                '';
                ' 1. Accepts a folder containing sequential PNG frames as input.';
                ' 2. Automatically detects and sorts frames using natural number ordering.';
                ' 3. Provides two stabilization algorithms: Gradient Correlation and SURF feature matching.';
                ' 4. Allows user to set reference frame and output frame rate.';
                ' 5. Processes frames in parallel for optimized performance.';
                ' 6. Generates stabilized output video with user-defined parameters.';
                '';
                ' INPUT PARAMETERS:';
                '';
                ' - inputFolder: Directory containing PNG frame sequence.';
                ' - FrameData: Cell array of loaded image frames.';
                ' - referenceFrame: User-selected reference frame for stabilization.';
                ' - outputFPS: Desired frame rate for output video.';
                ' - outputFolder: Destination for processed/stabilized frames.';
                ' - algorithm: Choice between Gradient Correlation or SURF methods.';
                '';
                ' OUTPUT:';
                ' - Stabilized video sequence saved to specified output folder.';
                ' - Maintains original video quality while reducing motion artifacts.';
                '';
                ' ALGORITHMS:';
                '';
                ' - GRADIENT CORRELATION: Uses gradient information for motion estimation,';
                '   effective for general stabilization with good performance.';
                ' - SURF (Speeded Up Robust Features): Feature-based approach that detects';
                '   and matches distinctive image features for precise motion compensation.';
                '';
                ' FEATURES:';
                '';
                ' - Intuitive graphical user interface for easy parameter configuration.';
                ' - Real-time progress tracking during processing.';
                ' - Support for large frame sequences through optimized memory handling.';
                ' - Flexible output settings (frame rate, reference frame selection).';
                ' - Error handling and user feedback throughout the process.';
                '';
                ' REQUIREMENTS:';
                '';
                ' - MATLAB with Image Processing Toolbox.';
                ' - MATLAB with Computer Vision Toolbox (for SURF method).';
                ' - Parallel Computing Toolbox (recommended for better performance).';
                ' - Input frames must be in PNG format and sequentially numbered.';
                '';
                ' USAGE EXAMPLE:';
                '   1. Click "Select input folder" to choose frame directory.';
                '   2. Select output folder for stabilized results.';
                '   3. Set desired output FPS and reference frame.';
                '   4. Choose stabilization algorithm (Gradient Correlation or SURF).';
                '   5. Click "Start" to begin stabilization process.';
                '';
                ' ==============================================================================';
                };

            % Create a dialog box with the text
            fig = uifigure('Name', 'STABA Stabilization Tool Info', 'Position', [100 100 800 600]);
            txt = uitextarea(fig, 'Value', infoText, 'Position', [20 20 760 560], ...
                'FontName', 'FixedWidth', 'Editable', 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GradientCorrelationButton = uiradiobutton(app.ButtonGroup);
            app.GradientCorrelationButton.Text = 'Gradient Correlation';
            app.GradientCorrelationButton.Value = true;
            app.SURFButton = uiradiobutton(app.ButtonGroup);
            app.SURFButton.Text = 'SURF';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.Value = 'Selected algorithm:';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SelectoutputfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputfolderButtonPushed, true);
            app.SelectoutputfolderButton.Text = 'Select output folder';
            app.SetreferenceframeButton = uibutton(app.UIFigure, 'push');
            app.SetreferenceframeButton.ButtonPushedFcn = createCallbackFcn(app, @SetreferenceframeButtonPushed, true);
            app.SetreferenceframeButton.Text = 'Set reference frame';
            app.SetoutputfpsButton = uibutton(app.UIFigure, 'push');
            app.SetoutputfpsButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputfpsButtonPushed, true);
            app.SetoutputfpsButton.Text = 'Set output fps';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Suggested:';
            app.FromEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'Selected:';
            app.FromEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField_3.Value = 1;
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = 'Selected:';
            app.FromEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = 'Total:';
            app.SelectinputfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectinputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinputfolderButtonPushed, true);
            app.SelectinputfolderButton.Text = 'Select input folder';
            app.AutomaticAlignmentButton = uibutton(app.UIFigure, 'push');
            app.AutomaticAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticAlignmentButtonPushed, true);
            app.AutomaticAlignmentButton.Text = 'Automatic Alignment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STABA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end