classdef STABM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ManualAlignmentButton     matlab.ui.control.Button
        SelectinputfolderButton   matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        EditField_2               matlab.ui.control.EditField
        FromEditField_2           matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.EditField
        FromEditField             matlab.ui.control.NumericEditField
        SetoutputfpsButton        matlab.ui.control.Button
        SelectoutputfolderButton  matlab.ui.control.Button
    end


    properties (Access = private)
        FrameData
        VideoFrameRate
        OutputFolder
        inputFolder
        imageFiles
        ProgressBar                 % Progress bar dialog
    end

    methods (Access = private)

        function [sorted, index] = sort_nat(~, cellArray)
            % Natural order sort for strings with numbers (e.g., img1, img2, img10)

            expr = '\d+';
            numPart = regexp(cellArray, expr, 'match');

            numVal = cellfun(@(x) str2double(x{1}), numPart);
            [~, index] = sort(numVal);
            sorted = cellArray(index);
        end


        function updateProgressBar(app, message, value)
            % Update progress bar with message and value
            if isvalid(app.ProgressBar)
                app.ProgressBar.Message = message;
                app.ProgressBar.Value = value;
                drawnow;
            end
        end

        function closeProgressBar(app)
            % Close progress bar if it exists
            if ~isempty(app.ProgressBar) && isvalid(app.ProgressBar)
                close(app.ProgressBar);
                app.ProgressBar = [];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FromEditField_2.Value = 0; % Default value for output FPS
        end

        % Button pushed function: SetoutputfpsButton
        function SetoutputfpsButtonPushed(app, event)
            prompt = {'Enter the desired FPS:'}; % question
            dlgtitle = 'Set Output FPS'; % dialog title
            dims = [1 35];
            definput = {num2str(app.FromEditField.Value)}; % Default value
            response = inputdlg(prompt, dlgtitle, dims, definput);

            if ~isempty(response)
                fps_output = str2double(response{1});

                % Check if the input is a valid number
                if isnan(fps_output)
                    uialert(app.UIFigure, 'Please enter a valid number.', 'Error');
                    return;
                end

                % Check if the value is negative or zero
                if fps_output <= 0
                    uialert(app.UIFigure, 'FPS must be greater than zero.', 'Error');
                    return;
                end

                % If everything is valid, update the field
                app.FromEditField_2.Value = fps_output;
            end
        end

        % Button pushed function: SelectoutputfolderButton
        function SelectoutputfolderButtonPushed(app, event)
            folderPath = uigetdir('', 'Select the output folder');
            if folderPath ~= 0
                app.OutputFolder = folderPath;
                uialert(app.UIFigure, ['Selected folder: ' folderPath], 'Output Folder');
            else
                uialert(app.UIFigure, 'No folder selected.', 'Warning');
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Retrieve frame data and total number of frames
            Frame_Data = app.FrameData;
            numFrames = numel(Frame_Data);
            outputFolder = app.OutputFolder;

            % Check if output folder has been selected
            if isempty(outputFolder)
                uialert(app.UIFigure, 'Please select an output folder first.', 'Error');
                return;
            end

            % Ask user for frame range to align (e.g., from 10 to 50)
            prompt = {'Start frame index:', 'End frame index:'};
            dlgtitle = 'Manual Alignment Range';
            dims = [1 60];
            definput = {'1', num2str(numFrames)};
            risposta = inputdlg(prompt, dlgtitle, dims, definput);

            % If user presses "Cancel", exit function
            if isempty(risposta)
                return;
            end

            % Convert input values to numbers
            startFrame = str2double(risposta{1});
            endFrame = str2double(risposta{2});

            % Check if values are valid
            if isnan(startFrame) || isnan(endFrame) || startFrame < 1 || endFrame > numFrames || startFrame > endFrame
                uialert(app.UIFigure, 'Invalid frame range.', 'Error');
                return;
            end

            % Define selected frames for alignment
            selectedFrames = startFrame:endFrame;
            nSelected = numel(selectedFrames); % Total number of frames to align
            alignedFrames = Frame_Data; % Initially all frames are identical

            % Array to store user click coordinates
            % Each row: [frame_index, x_coordinate, y_coordinate]
            userPoints = zeros(nSelected, 3);

            % Vector to indicate if a frame has a valid click
            hasValidClick = false(nSelected, 1);

            % COMMON target coordinates for all clicks
            % This will be the position where ALL selected points will be brought
            commonTargetX = 0;
            commonTargetY = 0;

            % Create progress bar in the main app window
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Point Selection - Manual Mode', ...
                'Message', 'Initializing...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off');

            % PHASE 1: Manual point acquisition for EACH frame
            for idx = 1:nSelected
                i = selectedFrames(idx);               % Original frame index
                currentFrame = Frame_Data{i};           % Get current frame

                % Update progress bar
                d.Value = idx / nSelected;
                d.Message = sprintf('Frame %d/%d - Select the SAME physical point', idx, nSelected);
                drawnow;

                % Show a new window for point selection with zoom and pan enabled
                f = figure('Name', sprintf('Frame %d/%d - Select REFERENCE POINT', idx, nSelected), ...
                    'NumberTitle', 'off', ...
                    'Position', [100 100 800 600]);

                imshow(currentFrame);                  % Show color frame
                drawnow;

                zoom on; pan on;                             % Enable zoom and pan
                title({'ZOOM AND PAN to find the EXACT SAME PHYSICAL POINT', ...
                    'This should be the SAME object/feature in the REAL WORLD', ...
                    'Press ENTER when ready to click on the point'}, ...
                    'FontSize', 10, 'FontWeight', 'bold');

                % Wait for ENTER key to confirm zoom/pan is done
                waitforbuttonpress;
                keyPressed = get(gcf, 'CurrentCharacter');

                while ~strcmp(keyPressed, char(13))  % char(13) = Enter key
                    waitforbuttonpress;
                    keyPressed = get(gcf, 'CurrentCharacter');
                end

                zoom off; pan off;                          % Disable zoom and pan
                title('CLICK on the EXACT SAME PHYSICAL POINT as in previous frames', ...
                    'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red');

                % Show the point from the first frame if it exists (as reference)
                if idx > 1 && hasValidClick(1)
                    firstPoint = userPoints(1, 2:3);
                    hold on;
                    % Draw a green cross at the position of the first frame
                    plot(firstPoint(1), firstPoint(2), 'g+', 'MarkerSize', 20, 'LineWidth', 3);
                    plot(firstPoint(1), firstPoint(2), 'go', 'MarkerSize', 15, 'LineWidth', 2);
                    text(firstPoint(1) + 15, firstPoint(2) - 15, 'Target position', ...
                        'Color', 'green', 'FontSize', 10, 'FontWeight', 'bold', ...
                        'BackgroundColor', 'black');
                    hold off;

                    % Calculate how much the current point is shifted relative to the first one
                    title(sprintf('AIM FOR THE GREEN CROSS (Frame 1: %.1f, %.1f)', ...
                        firstPoint(1), firstPoint(2)), ...
                        'FontSize', 10, 'FontWeight', 'bold');
                end

                % Loop until a valid click is obtained
                validClick = false;
                while ~validClick
                    try
                        [xClick, yClick] = ginput(1);
                    catch
                        % If the window is closed, reopen it
                        close(f);
                        f = figure('Name', sprintf('Frame %d/%d - Select REFERENCE POINT', idx, nSelected), ...
                            'NumberTitle', 'off', ...
                            'Position', [100 100 800 600], ...
                            'MenuBar', 'none', ...
                            'ToolBar', 'none');
                        imshow(currentFrame);
                        title('Please click on the point', 'FontSize', 10, 'FontWeight', 'bold');
                        continue;
                    end

                    % Verify that the click is within the image boundaries
                    if ~isempty(xClick) && ~isempty(yClick) && ...
                            xClick >= 1 && xClick <= size(currentFrame, 2) && ...
                            yClick >= 1 && yClick <= size(currentFrame, 1)

                        validClick = true;
                        userPoints(idx, :) = [i, xClick, yClick];
                        hasValidClick(idx) = true;

                        % If it is the first frame, store the coordinates as common target
                        if idx == 1
                            commonTargetX = xClick;
                            commonTargetY = yClick;
                            targetMsg = 'FIRST POINT SELECTED - This will be the target for all frames';
                        else
                            % Calculate how far we are from the target
                            distance = sqrt((xClick - commonTargetX)^2 + (yClick - commonTargetY)^2);
                            targetMsg = sprintf('Distance from target: %.1f pixels', distance);
                        end

                        % Display the selected point and the target
                        hold on;
                        % Selected point (red)
                        plot(xClick, yClick, 'r+', 'MarkerSize', 25, 'LineWidth', 3);
                        plot(xClick, yClick, 'ro', 'MarkerSize', 20, 'LineWidth', 2);
                        text(xClick + 20, yClick - 20, sprintf('Selected: (%.1f, %.1f)', xClick, yClick), ...
                            'Color', 'red', 'FontSize', 10, 'FontWeight', 'bold', ...
                            'BackgroundColor', 'black');

                        % Common target (green)
                        if idx > 1
                            plot(commonTargetX, commonTargetY, 'g+', 'MarkerSize', 25, 'LineWidth', 3);
                            plot(commonTargetX, commonTargetY, 'go', 'MarkerSize', 20, 'LineWidth', 2);
                            text(commonTargetX + 20, commonTargetY + 20, sprintf('Target: (%.1f, %.1f)', commonTargetX, commonTargetY), ...
                                'Color', 'green', 'FontSize', 10, 'FontWeight', 'bold', ...
                                'BackgroundColor', 'black');

                            % Line showing the necessary displacement
                            plot([xClick, commonTargetX], [yClick, commonTargetY], 'y--', 'LineWidth', 2);
                        end
                        hold off;

                        title({targetMsg, 'Loading next frame...'}, ...
                            'FontSize', 10, 'FontWeight', 'bold');

                        pause(3); % Show the point for 3 seconds
                    else
                        title('Invalid click! Click inside the image.', ...
                            'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red');
                    end
                end

                close(f); % Close the window
            end

            close(d); % Close the progress bar

            % Verify that all frames have a valid click
            framesWithoutClick = find(~hasValidClick);
            if ~isempty(framesWithoutClick)
                missingFrames = selectedFrames(framesWithoutClick);
                errorMsg = sprintf('Missing points in frames: %s\n\nAll frames must have a selected point.', ...
                    num2str(missingFrames));
                uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
                return;
            end

            % PHASE 2: Application of translations to align ALL points to the common target
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Applying Manual Alignment...', ...
                'Message', 'Aligning all points to common target...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off');

            framesAligned = 0;

            for idx = 1:nSelected
                i = selectedFrames(idx);

                % Coordinates of the point selected by the user in this frame
                userX = userPoints(idx, 2);
                userY = userPoints(idx, 3);

                % Calculate the displacement needed to bring this point to the common target
                dx = commonTargetX - userX;
                dy = commonTargetY - userY;

                % Apply the translation
                currentFrame = Frame_Data{i};
                alignedFrames{i} = imtranslate(currentFrame, [dx, dy]);
                framesAligned = framesAligned + 1;

                % Update progress bar
                d.Value = idx / nSelected;
                d.Message = sprintf('Aligning frame %d/%d... (dx=%.1f, dy=%.1f)', ...
                    idx, nSelected, dx, dy);
            end

            % PHASE 3: Video saving
            fps = app.FromEditField_2.Value; % Selected frame rate

            % Build output file name
            [~, baseName] = fileparts(app.inputFolder);
            outputVideoName = baseName + "_manual_aligned.mp4";
            outputPath = fullfile(outputFolder, outputVideoName);

            writer = VideoWriter(outputPath, 'MPEG-4');
            writer.FrameRate = fps;
            open(writer);

            for i = 1:numFrames
                writeVideo(writer, alignedFrames{i});
            end

            close(writer);
            d.Value = 1;
            d.Message = 'Done!';
            pause(0.5);
            close(d);

            % Detailed summary
            % Calculate statistics on the displacements
            displacements = zeros(nSelected, 2);
            for idx = 1:nSelected
                displacements(idx, 1) = commonTargetX - userPoints(idx, 2);
                displacements(idx, 2) = commonTargetY - userPoints(idx, 3);
            end

            maxDx = max(abs(displacements(:,1)));
            maxDy = max(abs(displacements(:,2)));
            meanDx = mean(abs(displacements(:,1)));
            meanDy = mean(abs(displacements(:,2)));

            summaryMsg = sprintf(['MANUAL ALIGNMENT COMPLETED\n\n' ...
                'Common target position: (%.1f, %.1f)\n' ...
                'Frames aligned: %d\n\n' ...
                'Maximum displacement:\n' ...
                '  X: %.1f pixels\n' ...
                '  Y: %.1f pixels\n\n' ...
                'Average displacement:\n' ...
                '  X: %.1f pixels\n' ...
                '  Y: %.1f pixels\n\n' ...
                'All user clicks now appear at the same coordinates.'], ...
                commonTargetX, commonTargetY, framesAligned, ...
                maxDx, maxDy, meanDx, meanDy);

            uialert(app.UIFigure, summaryMsg, 'Alignment Complete', 'Icon', 'success');

            % Save the alignment data
            alignmentData = struct();
            alignmentData.commonTarget = [commonTargetX, commonTargetY];
            alignmentData.userPoints = userPoints;
            alignmentData.displacements = displacements;
            alignmentData.selectedFrames = selectedFrames;
            alignmentData.timestamp = datetime('now');

            dataFileName = fullfile(outputFolder, [baseName '_manual_aligned_data.mat']);
            save(dataFileName, 'alignmentData');

            fprintf('Alignment data saved to: %s\n', dataFileName);

            % Display displacement graph
            figure('Name', 'Manual Alignment Displacements', ...
                'NumberTitle', 'off', ...
                'Position', [100 100 900 500]);

            subplot(2,2,1);
            plot(selectedFrames, displacements(:,1), 'r-o', 'LineWidth', 2, 'MarkerSize', 6);
            xlabel('Frame Number');
            ylabel('X Displacement (pixels)');
            title('Horizontal Displacement (dx)');
            grid on;
            hold on;
            plot([selectedFrames(1), selectedFrames(end)], [meanDx, meanDx], 'r--', 'LineWidth', 1.5);
            legend('Displacement', sprintf('Mean: %.1f', meanDx));

            subplot(2,2,2);
            plot(selectedFrames, displacements(:,2), 'b-s', 'LineWidth', 2, 'MarkerSize', 6);
            xlabel('Frame Number');
            ylabel('Y Displacement (pixels)');
            title('Vertical Displacement (dy)');
            grid on;
            hold on;
            plot([selectedFrames(1), selectedFrames(end)], [meanDy, meanDy], 'b--', 'LineWidth', 1.5);
            legend('Displacement', sprintf('Mean: %.1f', meanDy));

            subplot(2,2,3);
            plot(userPoints(:,2), userPoints(:,3), 'ro', 'MarkerSize', 8, 'LineWidth', 2);
            hold on;
            plot(commonTargetX, commonTargetY, 'g+', 'MarkerSize', 20, 'LineWidth', 3);
            plot(commonTargetX, commonTargetY, 'go', 'MarkerSize', 15, 'LineWidth', 2);
            xlabel('Original X coordinate (pixel)');
            ylabel('Original Y coordinate (pixel)');
            title('Original User Click Positions');
            grid on;
            legend('User clicks', 'Common target', 'Location', 'best');

            sgtitle(sprintf('Manual Alignment Analysis - Target: (%.1f, %.1f)', commonTargetX, commonTargetY));
        end

        % Button pushed function: SelectinputfolderButton
        function SelectinputfolderButtonPushed(app, event)
            app.inputFolder = uigetdir(pwd, 'Select frame folder');

            if app.inputFolder == 0
                uialert(app.UIFigure, 'No folder selected', 'Warning');
                return;
            end

            % Load the list of images
            app.imageFiles = dir(fullfile(app.inputFolder, '*.png'));
            if isempty(app.imageFiles)
                uialert(app.UIFigure, 'No PNG files found in the folder', 'Error');
                return;
            end

            % Sort files numerically
            [~, idx] = app.sort_nat({app.imageFiles.name});
            app.imageFiles = app.imageFiles(idx);

            uialert(app.UIFigure, sprintf('Loaded %d frames', length(app.imageFiles)), 'Success');

            % Try to recover the FPS from VideoStructure if STABM was launched by ViFoSe
            fps = 30;  % Default value

            try
                % If VideoStructure exists in the workspace (passed from ViFoSe)
                if evalin('base', 'exist(''VideoStructure'', ''var'')')
                    VideoStructure = evalin('base', 'VideoStructure');
                    if isfield(VideoStructure, 'frameRate')
                        fps = VideoStructure.frameRate;
                        uialert(app.UIFigure, ...
                            sprintf('FPS loaded from ViFoSe: %.2f', fps), ...
                            'FPS Detected');
                    else
                        error('VideoStructure exists but frameRate field not found');
                    end
                else
                    % If STABM is launched standalone, use default FPS.
                    uialert(app.UIFigure, ...
                        'Using default FPS = 30. For original FPS, launch from ViFoSe.', ...
                        'Info', 'Icon', 'info');
                end
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Could not read video FPS. Using default FPS = 30.\nError: %s', ME.message), ...
                    'Warning', 'Icon', 'warning');
            end

            % Load each image into Frame_Data
            Frame_Data = cell(1, length(app.imageFiles));
            for i = 1:length(app.imageFiles)
                Frame_Data{i} = imread(fullfile(app.inputFolder, app.imageFiles(i).name));
            end

            % Save the extracted data in the app's properties for later use
            app.FrameData = Frame_Data;

            % Also save the frame rate in a separate property
            app.VideoFrameRate = fps;

            % Update input fields with the video information
            app.FromEditField.Value = app.VideoFrameRate; % Original video FPS
            app.FromEditField_2.Value = app.VideoFrameRate; % Default FPS for the stabilized video (same as original)
        end

        % Button pushed function: ManualAlignmentButton
        function ManualAlignmentButtonPushed(app, event)
            infoText = {
                ' STABM - MANUAL VIDEO STABILIZATION TOOL';
                '';
                ' ==============================================================================';
                ' AUTHOR: Abdelrahman Abdelaziz Mohamed';
                '';
                ' DATE  : [15/12/2025]';
                '';
                ' FUNCTION: STABM - Manual Frame Alignment';
                '';
                ' PURPOSE:';
                '';
                ' This MATLAB application performs manual video stabilization by allowing users';
                ' to select a common reference point in each frame for precise alignment. Unlike';
                ' template-matching approaches, this method relies entirely on user input: the';
                ' operator manually identifies the same physical point in every frame. The algorithm';
                ' then calculates and applies pure translational transformations to bring all';
                ' selected points to identical coordinates in the output video. This approach is';
                ' designed for scenarios requiring maximum precision, particularly in scientific';
                ' analysis, microscopy, medical imaging, and technical applications where specific';
                ' reference features must be maintained at fixed positions throughout the sequence.';
                '';
                ' ALGORITHMIC PRINCIPLE:';
                '';
                ' The core concept is "common target alignment":';
                ' 1. The user selects a physical reference point in the FIRST frame.';
                ' 2. This point''s coordinates become the COMMON TARGET for all subsequent frames.';
                ' 3. For each following frame, the user selects the SAME physical point';
                '    (which appears at different coordinates due to camera motion).';
                ' 4. The algorithm calculates the translation vector needed to move each';
                '    user-selected point to the common target coordinates.';
                ' 5. All frames are translated accordingly, resulting in a stabilized video';
                '    where the reference feature remains fixed at the target position.';
                '';
                ' FUNCTIONAL OVERVIEW:';
                '';
                ' 1. Accepts a folder containing sequential PNG frames as input.';
                ' 2. Automatically detects and sorts frames using natural number ordering.';
                ' 3. Allows user to select specific frame range for alignment.';
                ' 4. Provides interactive zoom/pan functionality for precise point identification.';
                ' 5. Visual feedback shows target position (green) and current selection (red).';
                ' 6. Calculates pure translation vectors based on user clicks.';
                ' 7. Applies calculated translations to stabilize the entire sequence.';
                ' 8. Generates comprehensive alignment statistics and visualization.';
                ' 9. Saves both the stabilized video and alignment metadata.';
                '';
                ' CRITICAL REQUIREMENT:';
                '';
                ' - The user MUST click on exactly the SAME PHYSICAL POINT in every frame.';
                ' - This point should be a distinctive, stable feature visible throughout the sequence.';
                ' - The point''s appearance may change position due to camera motion, but it must';
                '   represent the same real-world feature.';
                ' - Maximum precision is essential: inconsistent selection will degrade results.';
                '';
                ' INPUT PARAMETERS:';
                '';
                ' - inputFolder: Directory containing PNG frame sequence.';
                ' - FrameData: Cell array of loaded image frames.';
                ' - outputFPS: Desired frame rate for output video.';
                ' - outputFolder: Destination for processed/stabilized video.';
                ' - frameRange: Start and end frames for alignment process.';
                '';
                ' OUTPUT:';
                ' - Stabilized MP4 video file saved to specified output folder.';
                ' - MAT file containing alignment metadata (user points, displacements, target).';
                ' - Comprehensive statistics and displacement visualization plots.';
                ' - Maintains original video quality while applying precise manual alignment.';
                '';
                ' ALGORITHM DETAILS:';
                '';
                ' - Implements interactive point selection with zoom/pan capabilities.';
                ' - Uses ginput for precise coordinate acquisition.';
                ' - Calculates translation vectors: dx = targetX - currentX, dy = targetY - currentY.';
                ' - Applies pure translations using imtranslate function.';
                ' - No interpolation or automatic feature matching: completely manual control.';
                ' - Validates that all frames have user input before processing.';
                '';
                ' KEY FEATURES:';
                '';
                ' - Visual feedback showing target position (green cross) during selection.';
                ' - Real-time distance calculation between current point and target.';
                ' - Progress tracking with detailed status messages.';
                ' - Comprehensive error checking and validation.';
                ' - Detailed statistics including maximum and average displacements.';
                ' - Full data traceability with metadata saving.';
                ' - Multi-platform video format support (MP4 for Windows/Mac, AVI for Linux).';
                '';
                ' CRITICAL USER GUIDELINES:';
                '';
                ' 1. Choose a distinctive, stable reference point visible in ALL frames.';
                ' 2. Use zoom and pan features to achieve pixel-level accuracy.';
                ' 3. Select EXACTLY the same physical feature in every frame - consistency is paramount.';
                ' 4. Avoid points that may move or change appearance between frames.';
                ' 5. Note that small selection errors compound across frames.';
                ' 6. The green cross shows where your click SHOULD land (target position).';
                ' 7. The yellow line visualizes the translation that will be applied.';
                '';
                ' REQUIREMENTS:';
                '';
                ' - MATLAB with Image Processing Toolbox.';
                ' - Input frames must be in PNG format and sequentially numbered.';
                ' - User patience and precision during point selection process.';
                '';
                ' USAGE WORKFLOW:';
                '   1. Click "Select input folder" to choose frame directory.';
                '   2. Select output folder for stabilized video.';
                '   3. Set desired output FPS (defaults to original video FPS if available).';
                '   4. Click "Start" and specify frame range for alignment.';
                '   5. For each frame:';
                '      a. Zoom/pan to locate reference point (press ENTER when ready).';
                '      b. Click precisely on the same physical point as previous frames.';
                '      c. Observe visual feedback (target, distance, translation vector).';
                '   6. Repeat for all frames in the range.';
                '   7. Application processes frames and saves results automatically.';
                '   8. Review statistics and displacement plots.';
                '';
                ' PERFORMANCE CONSIDERATIONS:';
                '';
                ' - Processing time depends linearly on number of frames.';
                ' - Manual point selection is the most time-consuming phase.';
                ' - Translation application is computationally efficient.';
                ' - Memory usage scales with frame size and count.';
                '';
                ' VALIDATION AND ERROR HANDLING:';
                '';
                ' - Validates all user inputs (FPS, frame range, folder paths).';
                ' - Checks that points are selected for ALL frames in range.';
                ' - Ensures clicks are within image boundaries.';
                ' - Provides comprehensive error messages for troubleshooting.';
                '';
                ' OUTPUT METADATA STRUCTURE:';
                '   alignmentData.commonTarget   : [x, y] coordinates of common target';
                '   alignmentData.userPoints     : [frame_idx, x, y] for all user clicks';
                '   alignmentData.displacements  : [dx, dy] translation vectors applied';
                '   alignmentData.selectedFrames : array of processed frame indices';
                '   alignmentData.timestamp      : processing date and time';
                '';
                ' WARNING:';
                ' The quality of stabilization directly depends on the precision of point selection.';
                ' Inconsistent point selection will result in poor stabilization and may make the';
                ' output video worse than the original. Take time to be precise and consistent!';
                '';
                ' ADVANTAGES OVER TEMPLATE-MATCHING APPROACHES:';
                ' 1. No dependence on texture or feature richness in images.';
                ' 2. Works with low-contrast or homogeneous regions.';
                ' 3. Immune to illumination changes between frames.';
                ' 4. User maintains complete control over reference point selection.';
                ' 5. Can align based on semantic understanding of the scene.';
                '';
                ' LIMITATIONS:';
                ' 1. Extremely time-consuming for long sequences.';
                ' 2. Subject to human error and fatigue.';
                ' 3. Corrects only global translations (no rotation or scaling correction).';
                '';
                ' TYPICAL APPLICATIONS:';
                ' - Scientific video analysis with fixed reference markers.';
                ' - Microscopy time-lapse sequences.';
                ' - Medical imaging with anatomical landmarks.';
                ' - Industrial inspection with fiducial markers.';
                ' - Forensic video analysis requiring pixel-perfect alignment.';
                '';
                ' ==============================================================================';
                };

            % Create a dialog box with the text
            fig = uifigure('Name', 'STABM Manual Stabilization Tool Info', 'Position', [100 100 800 650]);
            txt = uitextarea(fig, 'Value', infoText, 'Position', [20 20 760 580], ...
                'FontName', 'FixedWidth', 'Editable', 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectoutputfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputfolderButtonPushed, true);
            app.SelectoutputfolderButton.Text = 'Select output folder';
            app.SetoutputfpsButton = uibutton(app.UIFigure, 'push');
            app.SetoutputfpsButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputfpsButtonPushed, true);
            app.SetoutputfpsButton.Text = 'Set output fps';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Suggested:';
            app.FromEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'Selected:';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SelectinputfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectinputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinputfolderButtonPushed, true);
            app.SelectinputfolderButton.Text = 'Select input folder';
            app.ManualAlignmentButton = uibutton(app.UIFigure, 'push');
            app.ManualAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @ManualAlignmentButtonPushed, true);
            app.ManualAlignmentButton.Text = 'Manual Alignment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STABM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end